classdef simon_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        YouloseLabel  matlab.ui.control.Label
        ScoreLabel    matlab.ui.control.Label
        Label         matlab.ui.control.Label
        QuitButton    matlab.ui.control.Button
        GreenButton   matlab.ui.control.Button
        YellowButton  matlab.ui.control.Button
        BlueButton    matlab.ui.control.Button
        RedButton     matlab.ui.control.Button
        blue_flash    matlab.ui.control.Image
        green_flash   matlab.ui.control.Image
        red_flash     matlab.ui.control.Image
        yellow_flash  matlab.ui.control.Image
        Image         matlab.ui.control.Image
    end

    properties (Access = public)
        game_sequence= randi(4, [1,85]); % creates the sequence of buttons to match throughout the entire game. A new, random sequence for each game. 
        sequence_length= 1; %creates a variable that sets the length of the simon sequence. This will increase by 1 each time the user inputs a correct sequence.
        user_sequence=[]; %initiates a blank array to be populated by the user
    end
    
    methods (Access = public) %% DONT EDIT: this is the break between properties and functions. Properties define variables. Functions execute. 
    %%%Game funcitons

    function score_display(app)
    app.Label.Text=num2str(app.sequence_length-1); %shows sequence length in the label text field
    end

    function loser(app)
    app.YouloseLabel.Visible="on";
    [y,Fs]=audioread("sounds/fail.mp3");
    sound(y,Fs);
    pause (3.0);
    end

    function game_flash_and_sound(app)
%         app.BlueButton.Visible="off";
%         app.GreenButton.Visible="off";
%         app.RedButton.Visible="off";
%         app.YellowButton.Visible="off";
        pause(0.3);
        app.user_sequence=[]; %%resets user sequence to blank after a successful round
        for i=1:app.sequence_length %only iterates the game loop X # of times, where X= sequence_length.
            current_button=app.game_sequence(i);
            if current_button == 1 %red button sequence
                %pause(0.25);
              app.red_flash.Visible="on";
              [y,Fs]=audioread("sounds/redSound.mp3");
              sound(y,Fs);
              pause(0.5);
              app.red_flash.Visible="off";
            elseif current_button == 2 %green button sequence
               %pause(0.25);
               app.green_flash.Visible = "on";
               [y,Fs]=audioread("sounds/greenSound.mp3");
               sound(y,Fs);
               pause(0.5);
               app.green_flash.Visible="off";
            elseif current_button == 3 %yellow button sequence
                %pause(0.25);
                app.yellow_flash.Visible = "on";
                [y,Fs]=audioread("sounds/yellowSound.mp3");
                sound(y,Fs);
                pause(0.5);
                app.yellow_flash.Visible="off";
            else %blue button sequence
                %pause(0.25);
                app.blue_flash.Visible="on";
                [y,Fs]=audioread("sounds/blueSound.mp3");
                sound(y,Fs);
                pause(0.5);
                app.blue_flash.Visible="off";
            end
         end
%         app.BlueButton.Visible="on";
%         app.GreenButton.Visible="on";
%         app.RedButton.Visible="on";
%         app.YellowButton.Visible="on";
    end
    
    function increase_seq_length(app) %%updates the sequence length by 1. To be implemented after successful turn/compatibility check
        app.sequence_length=app.sequence_length+1;
    end

    function check_compatibility(app) %%checks compatibility after each button press
            if ~isequal(app.user_sequence,app.game_sequence(1:length(app.user_sequence)))==1 %% if the same indices of each sequence are not equal
                loser(app); %shows the losing message in the app
                disp("You Lost"); %shows the losing message in the command window
                disp("Your score was: "); %shows the losing message in the command window
                disp(app.sequence_length); %shows the final score in the command window
                pause(1.0);
                delete(app); %%quit game, else keeps going
            end
    end


  %%%%%%%%%
    end %methods end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        pause(0.9); game_flash_and_sound(app); %will wait briefly before beginning the game
        app.YouloseLabel.Visible="off"; %hides the lose message by default
        
            function startupFcn(app)
                %set WindowButtonMotionFcn as mouse move function
                set(app.UIFigure, 'WindowButtonMotionFcn', @mouseMove); 
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
        delete(app) %quits app if quit button is pushed
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
        %%button will flash and make sound

              app.green_flash.Visible="on";
              [y,Fs]=audioread("sounds/greenSound.mp3");
              sound(y,Fs);
              pause(0.25);
              app.green_flash.Visible="off";

              app.user_sequence= [app.user_sequence 2]; %adds the green button to the user generated sequence

              check_compatibility(app);

              if length(app.user_sequence)==app.sequence_length
              increase_seq_length(app); %increases sequence length
              score_display(app); %displays the score
              game_flash_and_sound(app); %initiates a new round 
              end

        end

        % Button pushed function: YellowButton
        function YellowButtonPushed(app, event)
                %%button will flash and make sound

              app.yellow_flash.Visible="on";
              [y,Fs]=audioread("sounds/yellowSound.mp3");
              sound(y,Fs);
              pause(0.25);
              app.yellow_flash.Visible="off";

              app.user_sequence=[app.user_sequence 3]; %adds the yellow button to the user generated sequence

              check_compatibility(app);

              if length(app.user_sequence)==app.sequence_length
              increase_seq_length(app); %increases sequence length
              score_display(app); %displays the score
              game_flash_and_sound(app); %initiates a new round 
              end

        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
              %%button will flash and make sound
              app.blue_flash.Visible="on";
              [y,Fs]=audioread("sounds/blueSound.mp3");
              sound(y,Fs);
              pause(0.25);
              app.blue_flash.Visible="off";

              app.user_sequence= [app.user_sequence 4]; %adds the blue button to the user generated sequence

              check_compatibility(app);

              if length(app.user_sequence)==app.sequence_length
              increase_seq_length(app); %increases sequence length
              score_display(app); %displays the score
              game_flash_and_sound(app); %initiates a new round 
              end
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
              %%button will flash and make sound
              app.red_flash.Visible="on";
              [y,Fs]=audioread("sounds/redSound.mp3");
              sound(y,Fs);
              pause(0.25);
              app.red_flash.Visible="off";

              app.user_sequence= [app.user_sequence 1]; %adds the red button to the user generated sequence

              check_compatibility(app);

              if length(app.user_sequence)==app.sequence_length
              increase_seq_length(app); %increases sequence length
              score_display(app); %displays the score
              game_flash_and_sound(app); %initiates a new round 
              end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
%             clickLocation=get(gca,'CurrentPoint');
            
            set(0,'DefaultFigureVisible','off');
            obj=event.Source;
            pos=obj.Parent.CurrentPoint(1,1:2);
%             disp(pos);
            set(gca,"Units","Pixels"); 
            
              if pos(1)>=200 && pos(1)<=315 && pos(2)>=250 && pos(2)<=365 % green region
                    %disp('green');
                    app.green_flash.Visible="on";
                    [y,Fs]=audioread("sounds/greenSound.mp3");
                    sound(y,Fs);
                    pause(0.25);
                    app.green_flash.Visible="off";
  
                    app.user_sequence= [app.user_sequence 2]; %adds the green button to the user generated sequence
  
                    check_compatibility(app);
  
                    if length(app.user_sequence)==app.sequence_length
                        increase_seq_length(app); %increases sequence length
                        score_display(app); %displays the score
                        game_flash_and_sound(app); %initiates a new round 
                    end
 
              elseif pos(1)>=330 && pos(1)<=445 && pos(2)>=250 && pos(2)<=365 % red region
                    %disp('red');
                    app.red_flash.Visible="on";
                    [y,Fs]=audioread("sounds/redSound.mp3");
                    sound(y,Fs);
                    pause(0.25);
                    app.red_flash.Visible="off";

                    app.user_sequence= [app.user_sequence 1]; %adds the red button to the user generated sequence

                    check_compatibility(app);

                    if length(app.user_sequence)==app.sequence_length
                        increase_seq_length(app); %increases sequence length
                        score_display(app); %displays the score
                        game_flash_and_sound(app); %initiates a new round 
                    end   
               
              elseif pos(1)>=200 && pos(1)<=315 && pos(2)>=120 && pos(2)<=245 % yellow region
                    %disp ('yellow')
                    app.yellow_flash.Visible="on";
                    [y,Fs]=audioread("sounds/yellowSound.mp3");
                    sound(y,Fs);
                    pause(0.25);
                    app.yellow_flash.Visible="off";

                    app.user_sequence=[app.user_sequence 3]; %adds the yellow button to the user generated sequence

                    check_compatibility(app);

                    if length(app.user_sequence)==app.sequence_length
                        increase_seq_length(app); %increases sequence length
                        score_display(app); %displays the score
                        game_flash_and_sound(app); %initiates a new round 
                    end

              elseif pos(1)>=330 && pos(1)<=445 && pos(2)>=120 && pos(2)<=245 % blue region
                    %disp('blue')
                    app.blue_flash.Visible="on";
                    [y,Fs]=audioread("sounds/blueSound.mp3");
                    sound(y,Fs);
                    pause(0.25);
                    app.blue_flash.Visible="off";

                    app.user_sequence= [app.user_sequence 4]; %adds the blue button to the user generated sequence

                    check_compatibility(app);

                    if length(app.user_sequence)==app.sequence_length
                        increase_seq_length(app); %increases sequence length
                        score_display(app); %displays the score
                        game_flash_and_sound(app); %initiates a new round 
                    end
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'simon_1.0.jpg');
            app.yellow_flash = uiimage(app.UIFigure);
            app.yellow_flash.Visible = 'off';
            app.yellow_flash.ImageSource = fullfile(pathToMLAPP, 'images', 'yellow.jpg');
            app.red_flash = uiimage(app.UIFigure);
            app.red_flash.Visible = 'off';
            app.red_flash.ImageSource = fullfile(pathToMLAPP, 'images', 'red.jpg');
            app.green_flash = uiimage(app.UIFigure);
            app.green_flash.Visible = 'off';
            app.green_flash.ImageSource = fullfile(pathToMLAPP, 'images', 'green.jpg');
            app.blue_flash = uiimage(app.UIFigure);
            app.blue_flash.Visible = 'off';
            app.blue_flash.ImageSource = fullfile(pathToMLAPP, 'images', 'blue.jpg');
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Visible = 'off';
            app.RedButton.Text = 'Red';
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Visible = 'off';
            app.BlueButton.Text = 'Blue';
            app.YellowButton = uibutton(app.UIFigure, 'push');
            app.YellowButton.ButtonPushedFcn = createCallbackFcn(app, @YellowButtonPushed, true);
            app.YellowButton.Visible = 'off';
            app.YellowButton.Text = 'Yellow';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Visible = 'off';
            app.GreenButton.Text = 'Green';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score:';
            app.YouloseLabel = uilabel(app.UIFigure);
            app.YouloseLabel.Visible = 'off';
            app.YouloseLabel.Text = 'You lose!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simon_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end