classdef clickerTestingMK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        BlueButton    matlab.ui.control.Button
        YellowButton  matlab.ui.control.Button
        GreenButton   matlab.ui.control.Button
        RedButton     matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [A] = func(I)
           in_img = I;
A=imread(circle.png);
imagesc(A);
magnificationFactor=2;
A=imresize(A,[1000,1000]);
axis image;
axis off;
        end

color=A(:,:,1)==255 & A(:,:,2)==0 & A(:,:,3)==0;

for i=1:500 %%red
    for j=1:500
        if color(i,j)==1
            A(i,j,1)=175;
            A(i,j,2)=0;
            A(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000 %% blue
    for j=500:1000
        if color(i,j)==1
            A(i,j,1)=0;
            A(i,j,2)=0;
            A(i,j,3)=175;
        else
            continue
        end
    end
end

for i=1:500  %% green
    for j=500:1000
        if color(i,j)==1
            A(i,j,1)=0;
            A(i,j,2)=175;
            A(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000  %% yellow
    for j=1:500
        if color(i,j)==1
            A(i,j,1)=175;
            A(i,j,2)=175;
            A(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of the background color:
background=A(:,:,1)==255 & A(:,:,2)==255 & A(:,:,3)==255;

for i=1:1000
    for j=1:1000
        if background(i,j)==1
            A(i,j,1)=0;
            A(i,j,2)=0;
            A(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of middle square for image created above:
A(400:600,400:600,1)=0;
A(400:600,400:600,2)=0;
A(400:600,400:600,3)=0;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%% lite-red
B=imread(outfilename);
imagesc(B);
magnificationFactor=2;
B=imresize(B,[1000,1000]);
axis image;
axis off;
[m,n,o]=size(B);

Rcolor=B(:,:,1)==255 & B(:,:,2)==0 & B(:,:,3)==0;

for i=1:500 %%red
    for j=1:500
        if Rcolor(i,j)==1
            B(i,j,1)=255;
            B(i,j,2)=0;
            B(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000 %% blue
    for j=500:1000
        if Rcolor(i,j)==1
            B(i,j,1)=0;
            B(i,j,2)=0;
            B(i,j,3)=175;
        else
            continue
        end
    end
end

for i=1:500  %% green
    for j=500:1000
        if Rcolor(i,j)==1
            B(i,j,1)=0;
            B(i,j,2)=175;
            B(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000  %% yellow
    for j=1:500
        if Rcolor(i,j)==1
            B(i,j,1)=175;
            B(i,j,2)=175;
            B(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of the background color:
background=B(:,:,1)==255 & B(:,:,2)==255 & B(:,:,3)==255;

for i=1:1000
    for j=1:1000
        if background(i,j)==1
            B(i,j,1)=0;
            B(i,j,2)=0;
            B(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of middle square for image created above:
B(400:600,400:600,1)=0;
B(400:600,400:600,2)=0;
B(400:600,400:600,3)=0;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%% lite-blue
C=imread(outfilename);
imagesc(C);
magnificationFactor=2;
C=imresize(C,[1000,1000]);
axis image;
axis off;
[m,n,o]=size(B);

Bcolor=C(:,:,1)==255 & C(:,:,2)==0 & C(:,:,3)==0;

for i=1:500 %%red
    for j=1:500
        if Bcolor(i,j)==1
            C(i,j,1)=255;
            C(i,j,2)=0;
            C(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000 %% blue
    for j=500:1000
        if Bcolor(i,j)==1
            C(i,j,1)=0;
            C(i,j,2)=0;
            C(i,j,3)=255;
        else
            continue
        end
    end
end

for i=1:500  %% green
    for j=500:1000
        if Bcolor(i,j)==1
            C(i,j,1)=0;
            C(i,j,2)=175;
            C(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000  %% yellow
    for j=1:500
        if Bcolor(i,j)==1
            C(i,j,1)=175;
            C(i,j,2)=175;
            C(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of the background color:
background=C(:,:,1)==255 & C(:,:,2)==255 & C(:,:,3)==255;

for i=1:1000
    for j=1:1000
        if background(i,j)==1
            C(i,j,1)=0;
            C(i,j,2)=0;
            C(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of middle square for image created above:
C(400:600,400:600,1)=0;
C(400:600,400:600,2)=0;
C(400:600,400:600,3)=0;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%% lite-green
D=imread(outfilename);
imagesc(D);
magnificationFactor=2;
D=imresize(D,[1000,1000]);
axis image;
axis off;
[m,n,o]=size(D);

Gcolor=D(:,:,1)==255 & D(:,:,2)==0 & D(:,:,3)==0;

for i=1:500 %%red
    for j=1:500
        if Gcolor(i,j)==1
            D(i,j,1)=255;
            D(i,j,2)=0;
            D(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000 %% blue
    for j=500:1000
        if Gcolor(i,j)==1
            D(i,j,1)=0;
            D(i,j,2)=0;
            D(i,j,3)=175;
        else
            continue
        end
    end
end

for i=1:500  %% green
    for j=500:1000
        if Gcolor(i,j)==1
            D(i,j,1)=0;
            D(i,j,2)=255;
            D(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000  %% yellow
    for j=1:500
        if Gcolor(i,j)==1
            D(i,j,1)=175;
            D(i,j,2)=175;
            D(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of the background color:
background=D(:,:,1)==255 & D(:,:,2)==255 & D(:,:,3)==255;

for i=1:1000
    for j=1:1000
        if background(i,j)==1
            D(i,j,1)=0;
            D(i,j,2)=0;
            D(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of middle square for image created above:
D(400:600,400:600,1)=0;
D(400:600,400:600,2)=0;
D(400:600,400:600,3)=0;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%% lite-yellow
E=imread(outfilename);
imagesc(E);
magnificationFactor=2;
E=imresize(E,[1000,1000]);
axis image;
axis off;
[m,n,o]=size(E);

Ycolor=E(:,:,1)==255 & E(:,:,2)==0 & E(:,:,3)==0;

for i=1:500 %%red
    for j=1:500
        if Ycolor(i,j)==1
            E(i,j,1)=255;
            E(i,j,2)=0;
            E(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000 %% blue
    for j=500:1000
        if Ycolor(i,j)==1
            E(i,j,1)=0;
            E(i,j,2)=0;
            E(i,j,3)=175;
        else
            continue
        end
    end
end

for i=1:500  %% green
    for j=500:1000
        if Ycolor(i,j)==1
            E(i,j,1)=0;
            E(i,j,2)=175;
            E(i,j,3)=0;
        else
            continue
        end
    end
end

for i=500:1000  %% yellow
    for j=1:500
        if Ycolor(i,j)==1
            E(i,j,1)=255;
            E(i,j,2)=255;
            E(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of the background color:
background=E(:,:,1)==255 & E(:,:,2)==255 & E(:,:,3)==255;

for i=1:1000
    for j=1:1000
        if background(i,j)==1
            E(i,j,1)=0;
            E(i,j,2)=0;
            E(i,j,3)=0;
        else
            continue
        end
    end
end

%% set-up of middle square for image created above:
E(400:600,400:600,1)=0;
E(400:600,400:600,2)=0;
E(400:600,400:600,3)=0;
    end


    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.Text = 'Green';
            app.YellowButton = uibutton(app.UIFigure, 'push');
            app.YellowButton.Text = 'Yellow';
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.Text = 'Blue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clickerTestingMK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end