classdef simon_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GreenButton   matlab.ui.control.Button
        YellowButton  matlab.ui.control.Button
        BlueButton    matlab.ui.control.Button
        RedButton     matlab.ui.control.Button
        quitButton    matlab.ui.control.Button
        Image         matlab.ui.control.Image
    end

    properties (Access = private)
        game_sequence= randi(4, [1,85]); % creates the sequence of buttons to match throughout the entire game. A new, random sequence for each game. 
        sequence_length= 1; %creates a variable that sets the length of the simon sequence. This will increase by 1 each time the user inputs a correct sequence.
        user_sequence=[]; %initiates a blank array to be populated by the user
    end
    
    methods (Access = private) %% DONT EDIT: this is the break between properties and functions. Properties define variables. Functions execute. 
    %%%Game funcitons

    function button_flash_and_sound(app)
        for i=1:app.sequence_length %only iterates the game loop X # of times, where X= sequence_length.
            current_button=app.game_sequence(i);

            if current_button == 1 %red button sequence
              app.RedButton.Visible="on";
              [y,Fs]=audioread("https://s3.amazonaws.com/freecodecamp/simonSound2.mp3");
              sound(y,Fs);
              %app.RedButton.Visible="off";
            elseif current_button == 2 %green button sequence
               app.GreenButton.Visible = "on";
               [y,Fs]=audioread("https://s3.amazonaws.com/freecodecamp/simonSound1.mp3");
               sound(y,Fs);
               %app.GreenButton.Visible="off";
            elseif current_button == 3 %yellow button sequence
                app.YellowButton.Visible = "on";
                [y,Fs]=audioread("https://s3.amazonaws.com/freecodecamp/simonSound4.mp3");
                sound(y,Fs);
               % app.YellowButton.Visible="off";
            else %blue button sequence
                app.BlueButton.Visible="on";
                [y,Fs]=audioread("https://s3.amazonaws.com/freecodecamp/simonSound3.mp3");
                sound(y,Fs);
                %app.BlueButton.Visible="off";
            end
         end
    end
    
  %%%%%%%%%
    end %methods end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            button_flash_and_sound(app);
        end

        % Button pushed function: quitButton
        function quitButtonPushed(app, event)
        delete(app) %quits app if quit button is pushed
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Simon', 'simon_1.0.jpg');
            app.quitButton = uibutton(app.UIFigure, 'push');
            app.quitButton.ButtonPushedFcn = createCallbackFcn(app, @quitButtonPushed, true);
            app.quitButton.Text = 'quit';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.Visible = 'off';
            app.RedButton.Text = 'Red';
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.Visible = 'off';
            app.BlueButton.Text = 'Blue';
            app.YellowButton = uibutton(app.UIFigure, 'push');
            app.YellowButton.Visible = 'off';
            app.YellowButton.Text = 'Yellow';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.Visible = 'off';
            app.GreenButton.Text = 'Green';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simon_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end